classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        YValueEditField                matlab.ui.control.NumericEditField
        YValueEditFieldLabel           matlab.ui.control.Label
        DelButton                      matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        ZValueEditField                matlab.ui.control.NumericEditField
        ZValueEditFieldLabel           matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SpecifySpideryMeshButtonGroup  matlab.ui.container.ButtonGroup
        ForeGroundButton               matlab.ui.control.RadioButton
        RadialLinesButton              matlab.ui.control.RadioButton
        VanishPointButton              matlab.ui.control.RadioButton
        RearWindowButton               matlab.ui.control.RadioButton
        CurrentPositionEditField       matlab.ui.control.EditField
        GridLayout2                    matlab.ui.container.GridLayout
        OpenButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % flags
        drawVP_flag     % check wether VP is defined and drawn
        drawRW_flag     % check wether inner Rectangle(Rear Window) is drawn
        drawRL_flag     % check wether Radial Lines are drawn
        drawPO_flag     % check wether Polygons are drawn
        select_process  % selection of the button in buttongroup

        InputImage      % documentation of the Image
        
        % spider mesh
        VanishPoint     % Vanishing Point in size of 2x1 Vector
        OuterRect       % specify the four corners of outer rectangle with direction upleft-upright-downright-downleft in size of 2x4 Matriz
        OuterFixies     % 2x(radial_size*4) Matriz
        InnerRect       % specify the four corners of Rear Window with direction upleft-upright-downright-downleft in size of 2x4 Matriz
        InnerCorrespond % correspond points of InnerRect on the OuterRect based on the VP in size of 2x4 Matriz
        OuterCorrespond % correspond points of InnerRect on the imagined edge of OuterRect based on the VP in size of 2x4 Matriz
        Vertices        % 2d Pixels 2x12 Matriz 
        Coordination    % 3d Pixels 3x12 Matriz
        
        % Distinguish Foreground and Background
        ForegroundObj 
    end
    
    properties (Access = public)
        draw_outer_rect     % control the drawing of outer rect
        draw_inner_rect     % * of inner rect
        draw_vp             % * of Vanish Point
        draw_radial         % * of Radial Lines
        draw_polygons       % * of 2d Polygons of each side of the rebuilt 3d. 1x5 Vector with direction of leftwall, ceiling, rightwall, floor and rearwall
        draw_vertices 
        radial_size         % define the density of the radial lines
        
        Input_Y
        Input_Z
    end
    
    % specify VanishPoint, InnerRect, OuterRect and RadialLines (function)
    methods (Access = private)
        
        % check if mouse is within axes
        function [new_point, isInAxes] = check_mouse_within_axes(app)
            isInAxes = true;
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if cp(1) < 0 % x-axis
                cp(1) = 0;
                isInAxes = false;
            elseif cp(1) > size(app.InputImage,2)
                cp(1) = size(app.InputImage,2);
                isInAxes = false;
            end
            if cp(2) < 0 % y-axis
                cp(2) = 0;
                isInAxes = false;
            elseif cp(2) > size(app.InputImage,1)
                cp(2) = size(app.InputImage,1);
                isInAxes = false;
            end
            new_point = cp;
        end
        
        % draw the initial outer rectangle
        function specify_OuterRectangle(app)
            app.OuterRect = [[0,0]',[size(app.InputImage,2),0]',[size(app.InputImage,2),size(app.InputImage,1)]',[0,size(app.InputImage,1)]'];
            app.draw_outer_rect = drawrectangle(app.UIAxes, 'Position', [0,0,size(app.InputImage,2),size(app.InputImage,1)]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'InteractionsAllowed'};
            ValueArray = {'OuterRect', 0, 'white', 0, 'none',};
            set(app.draw_outer_rect, NameArray, ValueArray);
            % preparation of radiallines based on outer_fixies
            n = 1;
            for i = 1:app.radial_size
                for j = 1:length(app.OuterRect)
                    app.OuterFixies(:, n) = [round(i*app.OuterRect(1,j)/app.radial_size); app.OuterRect(2,j)];
                    app.OuterFixies(:, n+1) = [app.OuterRect(1,j); round(i*app.OuterRect(2,j)/app.radial_size)];
                    n = n + 2;
                end
            end
            app.OuterFixies = unique(app.OuterFixies', 'rows')';
        end
        
        % draw the initial vanishpoint and radiallines
        function specify_VanishPoint(app)
            % check wether the VP is drawed, if it is already drawed, get
            % its current position. And if its button is selected, set it
            % to interactable
            if app.drawVP_flag
                if app.select_process == 1
                    app.VanishPoint = app.draw_vp.Position';
                    app.update_RadialLines();
                    set(app.draw_vp, 'InteractionsAllowed', 'all');
                return;
                end
            end
            % init check weheter the mouse is within outerrect and plot the
            % selection of VanishPoint
            [~, isInAxes] = app.check_mouse_within_axes();
            if ~isInAxes
                return;
            end
            app.draw_vp = drawpoint(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor'};
            ValueArray = {'VanishPoint', 0, 'white'};
            set(app.draw_vp, NameArray, ValueArray);

            % set the flag of VP, means it is drawed.
            app.drawVP_flag = true;
            app.update_RadialLines();
        end

        % draw the inner rectangle
        function specify_RearWindow(app)
            if app.drawRW_flag
                if app.select_process == 2
                    inner_rect_pos = app.draw_inner_rect.Position;
                    app.InnerRect = [[inner_rect_pos(1);inner_rect_pos(2)],...
                                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)],...
                                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)+inner_rect_pos(4)],...
                                     [inner_rect_pos(1);inner_rect_pos(2)+inner_rect_pos(4)]];
                    app.update_RadialLines();
                    set(app.draw_inner_rect, 'InteractionsAllowed', 'all');
                return;
                end
            end
            app.draw_inner_rect = drawrectangle(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'FaceSelectable'};
            ValueArray = {'RearWindow', 0, 'white', 0, false};
            set(app.draw_inner_rect, NameArray, ValueArray);

            % set the flag of InnerRect as true means it has been drawn
            app.drawRW_flag = true;
        end
        
        % draw the radiallines
        function specify_RadialLines(app)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return;
            end
            if app.drawRL_flag
                if app.select_process == 3
                    % change VP Position based on trajectory of L2
                    set(app.draw_radial(end),'InteractionsAllowed','reshape');
                    while get(app.draw_radial(end),'Selected')
                        cp = app.UIAxes.CurrentPoint(1,1:2);                     
                        fixie_inner_3 = app.InnerRect(:,3);
                        fixie_inner_4 = app.InnerRect(:,4);
                        fixie_corres_3 = app.InnerCorrespond(:,3);
                        fixie_line_k = (fixie_inner_3(2)-fixie_corres_3(2))/(fixie_inner_3(1)-fixie_corres_3(1));
                        fixie_line_b = fixie_inner_3(2)-fixie_line_k*fixie_inner_3(1);
                        
                        new_line_k = (fixie_inner_4(2)-cp(2))/(fixie_inner_4(1)-cp(1));
                        new_line_b = fixie_inner_4(2) - new_line_k*fixie_inner_4(1);
        
                        app.VanishPoint = [(new_line_b-fixie_line_b)/(-new_line_k+fixie_line_k); new_line_k*(new_line_b-fixie_line_b)/(-new_line_k+fixie_line_k)+new_line_b];
                        set(app.draw_vp,'Position',app.VanishPoint')
                        app.update_RadialLines();
                    end
                else
                    try
                        set(app.draw_radial(end),'InteractionsAllowed','none');
                    catch
                    end
                end
                return;
            end
        end
        
        % update the position of radiallines and redraw
        function update_RadialLines(app)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return;
            end
            try 
                delete(app.draw_radial)
            catch
            end
            app.drawRL_flag = false;
            % plot the radial lines
            %%% need to be optimized
            for l = 1:length(app.OuterFixies)
                radial_line = [app.VanishPoint app.OuterFixies(:,l)]';
                app.draw_radial(l) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 0.5);
            end
            for m = 1:length(app.InnerRect)
                % calculate the Corresponding Points based on Current
                % VanishPoint and Current InnerRect
                [app.InnerCorrespond(:,m), ~] = app.correspond_point_to_outer_rect(app.VanishPoint, app.InnerRect(:,m));
                radial_line = [app.VanishPoint app.InnerCorrespond(:,m)]';
                app.draw_radial(l+m) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 2);
            end
            app.drawRL_flag = true;
        end
              
        % specify the imagined edges of outer rectangle and return the five polygons
        function specify_Polygons(app, vp, innerrect)
            if app.drawPO_flag
                return;
            end
            try 
                delete(app.draw_polygons);
                delete(app.draw_vertices);
                app.drawRL_flag = false;
            catch
            end
            [last_inner,last_outer] = app.correspond_point_to_outer_rect(vp, innerrect(:,4));
            last_innerRect = innerrect(:,4);
            for i = 1:length(innerrect)
                [app.InnerCorrespond(:,i),app.OuterCorrespond(:,i)] = app.correspond_point_to_outer_rect(vp, innerrect(:,i));
                this_inner = app.InnerCorrespond(:,i);
                this_outer = app.OuterCorrespond(:,i);
                this_innerRect = innerrect(:,i);
                if this_inner(1) == last_outer(1) || this_inner(2) == last_outer(2)
                    connection_pair = [last_outer this_inner];
                    app.draw_polygons(i) = drawpolygon(app.UIAxes,'Position',[last_innerRect connection_pair this_innerRect]','InteractionsAllowed','none','Color',[0 0.4470 0.7410]);
                elseif this_inner(1) == last_inner(1) || this_inner(2) == last_inner(2)
                    connection_pair = [last_inner this_inner];
                    app.draw_polygons(i) = drawpolygon(app.UIAxes,'Position',[last_innerRect connection_pair this_innerRect]','InteractionsAllowed','none','Color',[0.8500 0.3250 0.0980]);
                elseif this_outer(1) == last_inner(1) || this_outer(2) == last_inner(2)
                    connection_pair = [last_inner this_outer];
                    app.draw_polygons(i) = drawpolygon(app.UIAxes,'Position',[last_innerRect connection_pair this_innerRect]','InteractionsAllowed','none','Color',[0.9290 0.6940 0.1250]);
                elseif this_outer(1) == last_outer(1) || this_outer(2) == last_outer(2)
                    connection_pair = [last_outer this_outer];
                    app.draw_polygons(i) = drawpolygon(app.UIAxes,'Position',[last_innerRect connection_pair this_innerRect]','InteractionsAllowed','none','Color',[0.4940 0.1840 0.5560]);
                end
                app.Vertices(:,(i-1)*3+1:i*3)= [last_innerRect connection_pair];
                last_inner = this_inner;
                last_outer = this_outer;
                last_innerRect = this_innerRect;
            end
            app.draw_polygons(5) = drawpolygon(app.UIAxes,'Position',innerrect','InteractionsAllowed','none','Color',[0.6350 0.0780 0.1840]);
            for i = 1:length(app.Vertices)
                app.draw_vertices(i) = drawpoint(app.UIAxes,'Position',app.Vertices(:,i)','InteractionsAllowed','none','Label',string(i));
            end
            xlim(app.UIAxes,[min(app.Vertices(1,:)),max(app.Vertices(1,:))]);
            ylim(app.UIAxes,[min(app.Vertices(2,:)),max(app.Vertices(2,:))]);
            app.drawPO_flag = true;
        end
        
        % specify the 3D Coordinates of the 12 Vertices, which determine
        % the Polygons
        function specify_Vertices_3D(app)
            d12 = app.calculate_distance(app.Vertices(:,1),app.Vertices(:,2));
            d14 = app.calculate_distance(app.Vertices(:,1),app.Vertices(:,4));
            d43 = app.calculate_distance(app.Vertices(:,4),app.Vertices(:,3));
            d45 = app.calculate_distance(app.Vertices(:,4),app.Vertices(:,5));
            d76 = app.calculate_distance(app.Vertices(:,7),app.Vertices(:,6));
            d78 = app.calculate_distance(app.Vertices(:,7),app.Vertices(:,8));
            d109 = app.calculate_distance(app.Vertices(:,10),app.Vertices(:,9));
            d1011 = app.calculate_distance(app.Vertices(:,10),app.Vertices(:,11));
            
            x = d12/d14*app.Input_Y;
            z = app.Input_Z;
            y = app.Input_Y;

            z23 = (d43*d76*d109)/(d45*d78*d1011)*z;
            z56 = (d76*d109)/(d78*d1011)*z;
            z89 = d109/d1011*z;
            app.Coordination = [0,0,0;0,0,z23;0,y,z23;0,y,0;0,y,z56;x,y,0;x,y,z89;x,0,z89;x,0,0;x,0,z;0,0,z];
        end
        
        function specify_ForegroundObjects(app)
            app.clear_drawing('all');
            while app.select_process == 4
                poly = drawpolygon(app.UIAxes);
                while ~app.AddButton.Value && ~app.DelButton.Value
                    if app.AddButton.Value
                        app.ForegroundObj{end+1} = poly;
                    end
                end
            end

        end
        
        function clear_drawing(app, mode)
            switch mode
                case 'RL'
                    try
                        delete(app.draw_radial);
                        app.drawRL_flag = false;
                    catch
                    end
                case 'VP'
                    try 
                        delete(app.draw_vp);
                        app.drawVP_flag = false;
                    catch
                    end
                case 'RW'
                    try
                        delete(app.draw_inner_rect);
                        app.drawRW_flag = false;
                    catch
                    end
                case 'PO'
                    try
                        delete(app.draw_polygons);
                        app.drawPO_flag = false;
                    catch
                    end
                    try delete(app.draw_vertices);
                    catch
                    end
                case 'all'
                    app.clear_drawing('RL');
                    app.clear_drawing('VP');
                    app.clear_drawing('RW');
                    app.clear_drawing('PO');
            end
        end
        
        % calculate the eucledean distance between two points
        function distance = calculate_distance(app, p1, p2)
            distance = norm(p2-p1);
        end

        % project the radiallines of innerrect on the outerrect
        function [CorrespondPoint, CorrespondPoint_Outer] = correspond_point_to_outer_rect(app, point, compared_point)
            % determine the zone of the point in comparison to the compared
            % point
            if point(1) == compared_point(1)
                if point(2) > compared_point(2)
                    CorrespondPoint = [point(1); 0];
                elseif point(2) < compared_point(2)
                    CorrespondPoint = [point(1); size(app.InputImage,2)];
                else
                    CorrespondPoint = point;
                end
                return;
            else
                k = (point(2)-compared_point(2))/(point(1)-compared_point(1));
                b = point(2)-k*point(1);
                if point(1) > compared_point(1)
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-0));
                    theta_2 = atan((compared_point(2)-0)/(compared_point(1)-0));
                    if phi < theta_1
                        % edge below
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                        % outer leftside
                        CorrespondPoint_Outer = [0; b];
                    elseif phi >= theta_1 && phi < 0
                        % edge leftside
                        CorrespondPoint = [0; b];
                        % outer below
                        CorrespondPoint_Outer = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    elseif phi >= 0 && phi < theta_2
                        % edge leftside
                        CorrespondPoint = [0; b];
                        % outer above
                        CorrespondPoint_Outer = [-b/k; 0];
                    else
                        % edge above
                        CorrespondPoint = [-b/k; 0];
                        % outer leftside
                        CorrespondPoint_Outer = [0; b];
                    end
                else
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-0)/(compared_point(1)-size(app.InputImage,2)));
                    theta_2 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-size(app.InputImage,2)));
                    if phi < theta_1
                        % edge above
                        CorrespondPoint = [-b/k; 0];
                        % outer rightside
                        CorrespondPoint_Outer = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                    elseif phi >= theta_1 && phi < 0
                        % edge rightside
                        CorrespondPoint = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                        % outer above
                        CorrespondPoint_Outer = [-b/k; 0];
                    elseif phi >= 0 && phi < theta_2
                        % edge rightside
                        CorrespondPoint = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                        % outer below
                        CorrespondPoint_Outer = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    else
                        % edge below
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                        % outer rightside
                        CorrespondPoint_Outer = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            set(app.UIAxes,'DrawMode','fast')

            app.radial_size = 1;
            app.select_process = 1;
            app.YValueEditField.Value = 1;
            app.ZValueEditField.Value = 1;
             
            % Flags of Drawing
            app.drawVP_flag = false;
            app.drawRW_flag = false;
            app.drawRL_flag = false;
            app.drawPO_flag = false;
            
            % Drawing in form of arrays
            app.draw_radial = zeros(4*app.radial_size+4,1);
            app.draw_polygons = zeros(5,1);
            app.draw_vertices = zeros(12,1);
            
            app.InnerCorrespond = zeros(2,4);
            app.OuterCorrespond = zeros(2,4);
            app.OuterFixies = zeros(2,app.radial_size*4);
            app.Vertices = zeros(2, 12);
            app.Coordination = zeros(3, 12);
           
            app.ForegroundObj = cell();
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            startupFcn(app);
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            imshow(app.InputImage,'Parent',app.UIAxes);
            app.specify_OuterRectangle();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            tf = strcmp(app.UIFigure.SelectionType, 'normal');
            if tf == 1 % left click of the mouse
                switch app.select_process
                    case 1
                        app.specify_VanishPoint();
                    case 2
                        app.specify_RearWindow();
                    case 3
                        app.specify_RadialLines();
                    case 4
                        app.specify_ForegroundObjects();
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % update the VanishPoint
            if app.drawVP_flag
                if app.select_process == 1
                    app.specify_VanishPoint();
                else
                    try
                        set(app.draw_vp, 'InteractionsAllowed', 'none');
                    catch
                    end
                end
            end
            % update the InnerRect
            if app.drawRW_flag
                if app.select_process == 2
                    app.specify_RearWindow();
                else
                    try
                        set(app.draw_inner_rect, 'InteractionsAllowed', 'none');
                    catch
                    end
                end
            end
            % animation of RadialLines
            if app.select_process == 3
            end
        end

        % Selection changed function: SpecifySpideryMeshButtonGroup
        function SpecifySpideryMeshButtonGroupSelectionChanged(app, event)
            switch app.SpecifySpideryMeshButtonGroup.SelectedObject.Text
                case 'VanishPoint'
                    app.select_process = 1;
                case 'RearWindow'
                    app.select_process = 2;
                case 'RadialLines'
                    app.select_process = 3;
                case 'ForeGround'
                    app.select_process = 4;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.drawPO_flag = false;
            try
                delete(app.draw_radial);
            catch
            end
            app.drawRL_flag = false;
            save_VP = app.VanishPoint;
            save_Rect = app.InnerRect;
            app.Input_Y = app.YValueEditField.Value;
            app.Input_Z = app.ZValueEditField.Value;
            set(app.CurrentPositionEditField,'Value',sprintf('VanishPoint: %.2f, %.2f\n InnenXY: %.2f, %.2f', save_VP(:),save_Rect(:,1)));
            app.specify_Polygons(save_VP,save_Rect);
            app.specify_Vertices_3D();
            hold(app.UIAxes2,'on')
            for i = 1:length(app.Coordination) 
                plot3(app.UIAxes2,app.Coordination(i,1),app.Coordination(i,2),app.Coordination(i,3),'*');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CurrentPositionEditField = uieditfield(app.GridLayout3, 'text');
            app.SpecifySpideryMeshButtonGroup = uibuttongroup(app.GridLayout3);
            app.SpecifySpideryMeshButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpecifySpideryMeshButtonGroupSelectionChanged, true);
            app.SpecifySpideryMeshButtonGroup.Title = 'Specify Spidery Mesh';
            app.RearWindowButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.RearWindowButton.Tag = 'R';
            app.RearWindowButton.Text = 'RearWindow';
            app.VanishPointButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.VanishPointButton.Tag = 'V';
            app.VanishPointButton.Text = 'VanishPoint';
            app.VanishPointButton.Value = true;
            app.RadialLinesButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.RadialLinesButton.Tag = 'R';
            app.RadialLinesButton.Text = 'RadialLines';
            app.ForeGroundButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.ForeGroundButton.Tag = 'V';
            app.ForeGroundButton.Text = 'ForeGround';
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ZValueEditFieldLabel = uilabel(app.GridLayout3);
            app.ZValueEditFieldLabel.Text = 'Z Value';
            app.ZValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AddButton = uibutton(app.GridLayout3, 'push');
            app.AddButton.Text = 'Add';
            app.DelButton = uibutton(app.GridLayout3, 'push');
            app.DelButton.Text = 'Del';
            app.YValueEditFieldLabel = uilabel(app.GridLayout3);
            app.YValueEditFieldLabel.Text = 'Y Value';
            app.YValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, '无标题.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end